classdef Hackathon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AnswerEditField       matlab.ui.control.EditField
        AnswerEditFieldLabel  matlab.ui.control.Label
        StartButton           matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        questionAnswered = 0; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                app.TextArea.Value = "Hey friend! Answer 10 arithmetic questions in a row to receive a special and fun message. Goodluck fellow mathematican!";
                pause(5);
                app.TextArea.Value = ""
                % Initialize correct answer count
                correct_answers = 0;
            
                % Loop until user answers 10 problems correctly
                while correct_answers < 10
                    % Generate two random integers between 1 and 10
                    num1 = randi([1, 10]);
                    num2 = randi([1, 10]);
                    
                    % Generate a random operator (+, -, *, /)
                    operators = ['+', '-', '*', '/'];
                    operator_index = randi(length(operators));
                    operator = operators(operator_index);
                    
                    % Display the arithmetic problem
                    app.TextArea.Value = sprintf('What is %d %c %d? ', num1, operator, num2);
                    % question = app.TextArea.Value;

                    % Get user's answer
                    
                    pause(10);
                    user_answer = app.AnswerEditField.Value;
                    
                    % Evaluate the expression
                    correct_answer = eval([num2str(num1), operator, num2str(num2)]);
                    
                    % Check if user's answer is correct
                    if abs(str2double(user_answer) - correct_answer) < .01
                        correct_answers = correct_answers + 1;
                        app.TextArea.Value = 'Correct!';
                        pause(1);
                    else
                        app.TextArea.Value = sprintf('Incorrect. The correct answer is %d.', correct_answer);
                        pause(1);
                    end
                end
                
                % Display message once user answers 10 problems correctly
                fprintf('You are awesome!\n');

        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
        end

        % Value changed function: AnswerEditField
        function AnswerEditFieldValueChanged(app, event)
            value = app.AnswerEditField.Value;
            app.questionAnswered = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AnswerEditFieldLabel = uilabel(app.UIFigure);
            app.AnswerEditFieldLabel.Text = 'Answer';
            app.AnswerEditField = uieditfield(app.UIFigure, 'text');
            app.AnswerEditField.ValueChangedFcn = createCallbackFcn(app, @AnswerEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hackathon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end